classdef Alakazam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        TabGroup     matlab.ui.container.TabGroup
        ALAKAZAMTab  matlab.ui.container.Tab
        TOOLSTab     matlab.ui.container.Tab
        UIAxes       matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function fillToolStripMenus(app)
            app.fillAlakazamMenu();
            app.fillToolsMenu();
        end

        function fillAlakazamMenu(app)
            %% ALAKAZAMTAB:
            gridObj = uigridlayout(app.ALAKAZAMTab,[1 1],'Padding', [0 0 0 0],'ColumnSpacing', 5);

            % Create the widget
            ALAKAZAMToolbar = wt.Toolbar(gridObj);
            % Create a horizontal section
            section1 = wt.toolbar.HorizontalSection();
            section1.Title = "NORMAL BUTTONS";
            section1.addButton("open_24.png", "Open");
            section1.addButton("save_24.png", "Save");
            % Create a horizontal section with state buttons
            section2 = wt.toolbar.HorizontalSection();
            section2.Title = "STATE BUTTONS";
            stateButton1 = section2.addStateButton("","Mode 1");
            stateButton2 = section2.addStateButton("","Mode 2");
            stateButton3 = section2.addStateButton("","Mode 3");
            % Set the state of the buttons
            stateButton1.Value = true;
            stateButton2.Value = false;
            stateButton3.Value = false;
            % Attach the horizontal sections to the toolbar
            ALAKAZAMToolbar.Section = [
                section1
                section2
                ];

        end

        function fillToolsMenu(app)
            %% ALAKAZAMTAB:
            gridObj = uigridlayout(app.TOOLSTab,[1 1],'Padding', [0 0 0 0],'ColumnSpacing', 5);

            % Create the widget
            TOOLSToolbar = wt.Toolbar(gridObj);
            % Create a horizontal section with vertical subsection
            section3 = wt.toolbar.HorizontalSection();
            section3.Title = "MIXED HORIZONTAL / VERTICAL";
            section3.addButton("left_24.png","");
            section3.addButton("right_24.png","");
            % Create the vertical subsection
            section3v1 = section3.addVerticalSection();
            section3v1.addButton("play_24.png","Play");
            section3v1.addButton("stop_24.png","Stop");
            % Add a manual component to the  vertical section
            sliderObj = uislider("Parent",[]);
            section3v1.Component(end+1) = sliderObj;
            % Attach a callback to the slider
            % This is required for manually adding components
            sliderObj.ValueChangedFcn = @(src,evt)disp(evt);
            % Create more horizontal items
            section3.addButton("pause_24.png","Pause");
            % Add another basic section
            section4 = wt.toolbar.HorizontalSection();
            section4.Title = "HELP";
            section4.addButton('help_24.png','Help');
            % Attach the horizontal sections to the toolbar
            TOOLSToolbar.Section = [
                section3
                section4
                ];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.GridLayout, 'ColumnSpacing', 0);
            app.fillToolStripMenus();
        end

        % Callback function
        function structButtonPushed(app, event)
            disp( struct(app.TabGroup));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ALAKAZAMTab = uitab(app.TabGroup);
            app.ALAKAZAMTab.Title = 'ALAKAZAM';
            app.TOOLSTab = uitab(app.TabGroup);
            app.TOOLSTab.Title = 'TOOLS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Alakazam

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end